classdef App_Dreem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        toEditField                  matlab.ui.control.NumericEditField
        toEditFieldLabel             matlab.ui.control.Label
        BandpassFilterHzEditField_2  matlab.ui.control.NumericEditField
        BandpassFilterHzEditField_2Label  matlab.ui.control.Label
        ScaleuvEditField_2           matlab.ui.control.NumericEditField
        ScaleuvEditField_2Label      matlab.ui.control.Label
        LoadDataButton               matlab.ui.control.Button
        SelectDatasetDropDown        matlab.ui.control.DropDown
        SelectDatasetDropDownLabel   matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        SleepSpindlesLabel_3         matlab.ui.control.Label
        UITable2                     matlab.ui.control.Table
        SelectEEGFileButton          matlab.ui.control.StateButton
        NextButton                   matlab.ui.control.StateButton
        BackButton                   matlab.ui.control.StateButton
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Plot Functions
            app.UIAxes.XMinorTick = 'on';
            app.UIAxes.XMinorGrid = 'on';
            app.UIAxes_2.XMinorTick = 'on';
            app.UIAxes_2.XMinorGrid = 'on';
            app.UIAxes_3.XMinorGrid = 'on';
            app.UIAxes_3.XMinorTick = 'on';
            
            
            % Global variables
            global simdata;
            simdata.fs = 200; simdata.epoch = 30;
            simdata.start_time = [];
            simdata.st = 1; simdata.sp = simdata.epoch*simdata.fs;
            simdata.max_epochs = 20;
            simdata.max_amp = 75;
            
            simdata.data = [];
            
            % expert #1 and #2
            simdata.ex1_spin = [];
            simdata.ex1_kcom = [];
            simdata.ex1_spin_tab = [];
            simdata.ex1_kcom_tab = [];
            simdata.ex2_spin = [];
            simdata.ex2_kcom = [];
            simdata.ex2_spin_tab = [];
            simdata.ex2_kcom_tab = [];
            
            % super expert
            simdata.ex_spin = [];
            simdata.ex_kcom = [];
            simdata.ex_spin_tab = [];
            simdata.ex_kcom_tab = [];
            
            simdata.spin_tab = [];
            simdata.kcom_tab = [];
            
            simdata.fname = [];
            simdata.chn = [];
            simdata.start_epoch = [];
            simdata.frame_num = 0;
            
            % List directories in the root path
            rootpath = dir('./');
            isub = [rootpath(:).isdir];
            foldnames = [char(rootpath(isub).name)];
            foldnames = cellstr(foldnames(3:end,:));
            
            % Add the list of folders as dropdown
            app.SelectPathDropDown.Items = foldnames;
            app.SelectPathDropDown.ItemsData = foldnames;
            
            % Disable the 'Back' and 'Next' buttons
            app.NextButton.Enable = 'off';
            app.BackButton.Enable = 'off';
            
            % Make Table
        end

        % Callback function
        function SelectDatasetDropDownValueChanged(app, event)
            
        end

        % Value changed function: SelectEEGFileButton
        function SelectEEGFileButtonValueChanged(app, event)
            
            % List the files in the selected path
            spath = strcat('./',app.SelectPathDropDown.Value,'/');
            rootpath = dir(spath);
            isub = ~[rootpath(:).isdir];
            filenames = [char(rootpath(isub).name)];
            filenames = cellstr(filenames);
            
            % Add the list of folders as dropdown
            app.SelectDatasetDropDown.Items = filenames;
            app.SelectDatasetDropDown.ItemsData = filenames;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            % Global variables
            global simdata;
            
            % Assign filename
            simdata.fname = strrep(char(app.SelectDatasetDropDown.Value),'.h5','');
            
            % Path of the .H5 file
            fpath = strcat('./',char(app.SelectPathDropDown.Value),'/', ...
                char(app.SelectDatasetDropDown.Value));
            
            % Read attributes
            simdata.fs = h5readatt(fpath,'/','sampling_rate');
            simdata.epoch = h5readatt(fpath,'/','epoch_len');
            simdata.start_time = h5readatt(fpath,'/','start_time');
            simdata.start_time = strrep(char(simdata.start_time),'.',':');
            
            % Load epoch number and channel
            [simdata.start_epoch, simdata.chn] = extract_epoch_number(char(simdata.fname));
            
            % Open the .H5 file and load channels
            if strcmp(char(simdata.chn), 'C4A1')
                data_a1 = h5read(fpath,'/A1Ref');
                data_c4 = h5read(fpath,'/C4Ref');
            elseif strcmp(char(simdata.chn), 'C3A2')
                data_a1 = h5read(fpath,'/A2Ref');
                data_c4 = h5read(fpath,'/C3Ref');
            end
            
            % Extract the diff of the two EEG channels
            data = data_a1 - data_c4;
            
            % Filter EEG data
            [ntFilt,lpFilt,hpFilt] = apply_lowpass_filter();
            data_notch = filtfilt(ntFilt,data);                 % notch filter
            data_lp = filtfilt(lpFilt, data_notch);             % zero-phase low pass
            data_hp = filtfilt(hpFilt, data_lp);                % zero-phase high pass
            simdata.data = data_hp;
            
            % Extract Spindle and K-Complex Signal
            [simdata.ex1_spin, simdata.ex1_kcom, simdata.ex1_spin_tab, simdata.ex1_kcom_tab] = ...
                load_expert_data(simdata.data, simdata.fs, ...
                simdata.epoch, simdata.start_time, simdata.start_epoch, ...
                simdata.fname, 'expert1');
            [simdata.ex2_spin, simdata.ex2_kcom, simdata.ex2_spin_tab, simdata.ex2_kcom_tab] = ...
                load_expert_data(simdata.data, simdata.fs, ...
                simdata.epoch, simdata.start_time, simdata.start_epoch, ...
                simdata.fname, 'expert2');
            [simdata.ex_spin, simdata.ex_kcom, simdata.ex_spin_tab, simdata.ex_kcom_tab] = ...
                load_super_expert_data(simdata.data, simdata.fs, ...
                simdata.epoch, simdata.start_time, simdata.start_epoch, ...
                simdata.fname, 'super_expert');
            
            % Union of Two Experts
            simdata.spin_tab = simdata.ex_spin_tab;
            simdata.kcom_tab = simdata.ex_kcom_tab;
            
            % Plot Epoch
            fs = simdata.fs; epoch = simdata.epoch; N = fs*epoch;
            t = linspace(0,epoch,N);
            plot(app.UIAxes, t, simdata.data(1:N), 'k'); hold(app.UIAxes, 'on'); 
            plot(app.UIAxes, t, simdata.ex1_spin(1:N), 'r'); hold(app.UIAxes, 'on');
            plot(app.UIAxes, t, simdata.ex1_kcom(1:N), 'b'); hold(app.UIAxes, 'off');
            app.UIAxes.YLim = ([-simdata.max_amp,+simdata.max_amp]);
            
            plot(app.UIAxes_2, t, simdata.data(1:N), 'k'); hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, t, simdata.ex2_spin(1:N), 'r'); hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, t, simdata.ex2_kcom(1:N), 'b'); hold(app.UIAxes_2, 'off');
            app.UIAxes_2.YLim = ([-simdata.max_amp,+simdata.max_amp]);
            
            plot(app.UIAxes_3, t, simdata.data(1:N), 'k'); hold(app.UIAxes_3, 'on');
            plot(app.UIAxes_3, t, simdata.ex_spin(1:N), 'r'); hold(app.UIAxes_3, 'on');
            plot(app.UIAxes_3, t, simdata.ex_kcom(1:N), 'b'); hold(app.UIAxes_3, 'off');
            app.UIAxes_3.YLim = ([-simdata.max_amp,+simdata.max_amp]);
            
            % Update Frame Count
            simdata.frame_num = simdata.frame_num + 1;
            
            % Update Tables
            app.UITable2.Data = filt_table_editable(simdata.spin_tab, simdata.frame_num);
            app.UITable2_2.Data = filt_table_editable(simdata.kcom_tab, simdata.frame_num);
            
            % Enable the 'Next' buttons
            app.NextButton.Enable = 'on';
            app.BackButton.Enable = 'off';
            
        end

        % Value changed function: NextButton
        function NextButtonValueChanged(app, event)
            
            % Global variables
            global simdata;
            
            % Update the pointers
            fs = simdata.fs; epoch = simdata.epoch; N = fs*epoch;
            st = simdata.sp+1; sp = simdata.sp+N;
            
            % Plot Epoch
            t = linspace(floor(st/fs),floor(sp/fs),N);
            plot(app.UIAxes, t, simdata.data(st:sp), 'k'); hold(app.UIAxes, 'on');
            plot(app.UIAxes, t, simdata.ex1_spin(st:sp), 'r'); hold(app.UIAxes, 'on');
            plot(app.UIAxes, t, simdata.ex1_kcom(st:sp), 'b'); hold(app.UIAxes, 'off');
            app.UIAxes.YLim = ([-simdata.max_amp,+simdata.max_amp]);
            
            plot(app.UIAxes_2, t, simdata.data(st:sp), 'k'); hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, t, simdata.ex2_spin(st:sp), 'r'); hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, t, simdata.ex2_kcom(st:sp), 'b'); hold(app.UIAxes_2, 'off');
            app.UIAxes_2.YLim = ([-simdata.max_amp,+simdata.max_amp]);
            
            plot(app.UIAxes_3, t, simdata.data(st:sp), 'k'); hold(app.UIAxes_3, 'on');
            plot(app.UIAxes_3, t, simdata.ex_spin(st:sp), 'r'); hold(app.UIAxes_3, 'on');
            plot(app.UIAxes_3, t, simdata.ex_kcom(st:sp), 'b'); hold(app.UIAxes_3, 'off');
            app.UIAxes_3.YLim = ([-simdata.max_amp,+simdata.max_amp]);
            
            simdata.frame_num = simdata.frame_num + 1;
            
            app.UITable2.Data = filt_table_editable(simdata.spin_tab, simdata.frame_num);
            app.UITable2_2.Data = filt_table_editable(simdata.kcom_tab, simdata.frame_num);
            
            % Update globa pointers
            simdata.st = st; simdata.sp = sp;
            
            % Enable/Disable the 'Back' buttons
            if simdata.st == 1
                app.BackButton.Enable = 'off';
            else
                app.BackButton.Enable = 'on';
            end
            
            % Enable/Disable the 'Next' buttons
            if simdata.sp == simdata.epoch*simdata.fs*simdata.max_epochs
                app.NextButton.Enable = 'off';
            else
                app.NextButton.Enable = 'on';
            end
            
        end

        % Value changed function: BackButton
        function BackButtonValueChanged(app, event)
            
            % Global variables
            global simdata;
            
            % Update the pointers
            fs = simdata.fs; epoch = simdata.epoch; N = fs*epoch;
            sp = simdata.sp-N; st = sp-N+1;
            
            % Plot Epoch
            t = linspace(floor(st/fs),floor(sp/fs),N);
            plot(app.UIAxes, t, simdata.data(st:sp), 'k'); hold(app.UIAxes, 'on');
            plot(app.UIAxes, t, simdata.ex1_spin(st:sp), 'r'); hold(app.UIAxes, 'on');
            plot(app.UIAxes, t, simdata.ex1_kcom(st:sp), 'b'); hold(app.UIAxes, 'off');
            app.UIAxes.YLim = ([-simdata.max_amp,+simdata.max_amp]);
            
            plot(app.UIAxes_2, t, simdata.data(st:sp), 'k'); hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, t, simdata.ex2_spin(st:sp), 'r'); hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, t, simdata.ex2_kcom(st:sp), 'b'); hold(app.UIAxes_2, 'off');
            app.UIAxes_2.YLim = ([-simdata.max_amp,+simdata.max_amp]);
            
            plot(app.UIAxes_3, t, simdata.data(st:sp), 'k'); hold(app.UIAxes_3, 'on');
            plot(app.UIAxes_3, t, simdata.ex_spin(st:sp), 'r'); hold(app.UIAxes_3, 'on');
            plot(app.UIAxes_3, t, simdata.ex_kcom(st:sp), 'b'); hold(app.UIAxes_3, 'off');
            app.UIAxes_3.YLim = ([-simdata.max_amp,+simdata.max_amp]);
            
            simdata.frame_num = simdata.frame_num - 1;
            
            app.UITable2.Data = filt_table_editable(simdata.spin_tab, simdata.frame_num);
            app.UITable2_2.Data = filt_table_editable(simdata.kcom_tab, simdata.frame_num);
            
            % Update globa pointers
            simdata.st = st; simdata.sp = sp;
            
            % Enable/Disable the 'Back' buttons
            if simdata.st == 1
                app.BackButton.Enable = 'off';
            else
                app.BackButton.Enable = 'on';
            end
            
            % Enable/Disable the 'Next' buttons
            if simdata.sp == simdata.epoch*simdata.fs*simdata.max_epochs
                app.NextButton.Enable = 'off';
            else
                app.NextButton.Enable = 'on';
            end
            
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            global simdata;
            i1 = event.Indices(1,1);
            i2 = event.Indices(1,2);
            if (event.PreviousData == 0) && (i2 == 4) && (event.NewData ~= 0)
                simdata.spin_tab = add_new_row(simdata.spin_tab,simdata.frame_num);
                simdata.spin_tab = edit_row(simdata.spin_tab,[i1,i2],simdata.frame_num,event.NewData);
                simdata.spin_tab(uint8(i1),uint8(i2)) = {event.NewData};
            elseif (event.PreviousData ~= 0) && (event.PreviousData ~= event.NewData) 
                simdata.spin_tab = edit_row(simdata.spin_tab,[i1,i2],simdata.frame_num,event.NewData);
            elseif (event.PreviousData == 0) && (event.PreviousData ~= event.NewData) 
                simdata.spin_tab = edit_row(simdata.spin_tab,[i1,i2],simdata.frame_num,event.NewData);
            end
        end

        % Callback function
        function UITable2_2CellEdit(app, event)
            global simdata;
            i1 = event.Indices(1,1);
            i2 = event.Indices(1,2);
            if (event.PreviousData == 0) && (i2 == 4) && (event.NewData ~= 0)
                simdata.kcom_tab = add_new_row(simdata.kcom_tab,simdata.frame_num);
                simdata.kcom_tab = edit_row(simdata.kcom_tab,[i1,i2],simdata.frame_num,event.NewData);
            elseif (event.PreviousData ~= 0) && (event.PreviousData ~= event.NewData) 
                simdata.kcom_tab = edit_row(simdata.kcom_tab,[i1,i2],simdata.frame_num,event.NewData);
            elseif (event.PreviousData == 0) && (event.PreviousData ~= event.NewData) 
                simdata.kcom_tab = edit_row(simdata.kcom_tab,[i1,i2],simdata.frame_num,event.NewData);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global simdata;
            save_and_exit(simdata.spin_tab,simdata.fname,'spindles','./super_expert/');
            save_and_exit(simdata.kcom_tab,simdata.fname,'kcomplexes','./super_expert/');
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fpz-F8')
            ylabel(app.UIAxes, 'Amplitude (uV)')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Fpz-F7')
            ylabel(app.UIAxes_2, 'Amplitude (uV)')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'F8-F7')
            ylabel(app.UIAxes_3, 'Amplitude (uV)')
            app.BackButton = uibutton(app.UIFigure, 'state');
            app.BackButton.ValueChangedFcn = createCallbackFcn(app, @BackButtonValueChanged, true);
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.UIFigure, 'state');
            app.NextButton.ValueChangedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.NextButton.Text = 'Next';
            app.SelectEEGFileButton = uibutton(app.UIFigure, 'state');
            app.SelectEEGFileButton.ValueChangedFcn = createCallbackFcn(app, @SelectEEGFileButtonValueChanged, true);
            app.SelectEEGFileButton.Text = 'Select EEG File';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'f'; 's'; 'd'; 'y/n'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true true true];
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.SleepSpindlesLabel_3 = uilabel(app.UIFigure);
            app.SleepSpindlesLabel_3.Text = 'Sleep Spindles';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SelectDatasetDropDownLabel = uilabel(app.UIFigure);
            app.SelectDatasetDropDownLabel.Text = 'Select Dataset';
            app.SelectDatasetDropDown = uidropdown(app.UIFigure);
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ScaleuvEditField_2Label = uilabel(app.UIFigure);
            app.ScaleuvEditField_2Label.Text = 'Scale (uv)';
            app.ScaleuvEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ScaleuvEditField_2.Value = 50;
            app.BandpassFilterHzEditField_2Label = uilabel(app.UIFigure);
            app.BandpassFilterHzEditField_2Label.Text = 'Bandpass Filter (Hz)';
            app.BandpassFilterHzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BandpassFilterHzEditField_2.Value = 1;
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Dreem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end